classdef audioplayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioplayerUIFigure       matlab.ui.Figure
        OpenButton                matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        VolumeSliderLabel         matlab.ui.control.Label
        VolumeSlider              matlab.ui.control.Slider
        NowplayingEditFieldLabel  matlab.ui.control.Label
        NowplayingEditField       matlab.ui.control.EditField
        HzSliderLabel             matlab.ui.control.Label
        eqSlider1                 matlab.ui.control.Slider
        EqualizerSwitchLabel      matlab.ui.control.Label
        EqualizerSwitch           matlab.ui.control.Switch
        ZeroallButton             matlab.ui.control.Button
        HzLabel                   matlab.ui.control.Label
        eqSlider2                 matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        HzLabel_2                 matlab.ui.control.Label
        eqSlider3                 matlab.ui.control.Slider
        HzLabel_3                 matlab.ui.control.Label
        eqSlider4                 matlab.ui.control.Slider
        HzLabel_4                 matlab.ui.control.Label
        eqSlider5                 matlab.ui.control.Slider
        kHzSliderLabel            matlab.ui.control.Label
        eqSlider6                 matlab.ui.control.Slider
        kHzSlider_2Label          matlab.ui.control.Label
        eqSlider7                 matlab.ui.control.Slider
        kHzSlider_3Label          matlab.ui.control.Label
        eqSlider8                 matlab.ui.control.Slider
        kHzSlider_4Label          matlab.ui.control.Label
        eqSlider9                 matlab.ui.control.Slider
        kHzSlider_5Label          matlab.ui.control.Label
        eqSlider10                matlab.ui.control.Slider
        TimeLabel                 matlab.ui.control.Label
        CurrentTimeLabel          matlab.ui.control.Label
        TotalTimeLabel            matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
    end

    
    properties (Access = public)

    end
    
    properties (Access = private)
        isPlaying = false; % Status of audioplayer
        fileReader; % variable to store dsp.AudioFileReader object
        deviceWriter; % variable to store audioDeviceWriter object
        equalizer; % variable to store graphicEQ object
        eqGains = zeros(1, 10); % equalizer gains, default 0dB (1 octave -> 10 frequencies)
        eqEnable = false;   % variable to store equalizer state (off/on)
        volume = 1; % default volume (1 == 100%)
        sliders;    % variable to store equalizer slider objects
        samplesPerFrame = 1024; % number of audio samples per frame
        currentSamples = 0; % initialize current sample counter
        trackLength = seconds(0); % initialize track length variable
        trackCurrentTime = seconds(0);  % initialize current elapsed time of track variable
    end
    
    methods (Access = private)
        
        function getTrackLength (app, file)
        %% Function that gets audio file length in format 'hh:mm:ss'
        % Gets track legth from given audio file
            app.trackLength = audioinfo(file).Duration;
            app.trackLength = seconds(app.trackLength);
            app.trackLength.Format = 'hh:mm:ss';
        end
        
        function updateCurrentTime (app, clear)
        %% Function for updating current elapsed time of track
        % If specified second argument as true statement, set counter to
        % zero and display, else if empty or false count on
            if nargin < 2
                clear = false; % set default statement to false if not specified
            end
            if clear == true
                app.currentSamples = 0; % if clear statement is true perform reset of counter
            end
            app.currentSamples = app.currentSamples + app.samplesPerFrame;    % increment current sample counter
            app.trackCurrentTime = seconds(app.currentSamples ./ app.fileReader.SampleRate); % get current elapsed time of track in seconds
            app.trackCurrentTime.Format = 'hh:mm:ss';   % set proper format of time
            app.CurrentTimeLabel.Text = string(app.trackCurrentTime);   % update time in GUI
        end
        
        function tagComponents(app)
            componentNames = fieldnames(app);
            for i = 1:length(componentNames)
                app.(componentNames{i}).Tag = componentNames{i};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.equalizer = graphicEQ('Bandwidth', "1 octave", 'Structure', "Cascade");
                app.sliders = [app.eqSlider1, app.eqSlider2, app.eqSlider3, app.eqSlider4, app.eqSlider5, ...
                    app.eqSlider6, app.eqSlider7, app.eqSlider8, app.eqSlider9, app.eqSlider10];
                app.trackLength.Format = 'hh:mm:ss';
                app.TotalTimeLabel.Text = string(app.trackLength);
                tagComponents(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [fileToPlay, filePath] = uigetfile('Audio files (*.wav;*.ogg;*.flac;*.au;*.aiff;*.aifc;*.aif;*.aifc;*.mp3;*.m4a;*.mp4)', 'Open audio file');
            if fileToPlay ~= 0
                app.fileReader = dsp.AudioFileReader(strcat(filePath, fileToPlay), "SamplesPerFrame", app.samplesPerFrame);
                app.deviceWriter = audioDeviceWriter('SampleRate', app.fileReader.SampleRate);
                app.equalizer.SampleRate = app.fileReader.SampleRate;
                app.NowplayingEditField.Value = fileToPlay;
                getTrackLength(app, strcat(filePath, fileToPlay));
                app.TotalTimeLabel.Text = string(app.trackLength);
            end
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.volume = changingValue./100;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.isPlaying = false;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.isPlaying = false;
            updateCurrentTime(app, true);
            release(app.fileReader);
            release(app.deviceWriter);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~app.isPlaying && ~isempty(app.fileReader)
                app.isPlaying = true;
            end
            while app.isPlaying && ~isDone(app.fileReader)  % loop for streaming audio frames
                frame = app.fileReader();   % read next audio frame from file
                if app.eqEnable     
                    frame = app.equalizer(frame);   % if equalizer enabled, apply equalizer settings at audio frames in buffer
                end
                frame = frame .* app.volume;    % apply volume level at audio frames in buffer
                app.deviceWriter(frame);    % write current audio frame to soundcard
                updateCurrentTime(app); % update current elapsed time of track in GUI label
                drawnow();  % process callbacks from GUI
            end
            if isDone(app.fileReader)
                app.isPlaying = false;
                updateCurrentTime(app, true);
            end
        end

        % Close request function: AudioplayerUIFigure
        function AudioplayerUIFigureCloseRequest(app, event)
            app.isPlaying = false;
            release(app.equalizer);
            if ~isempty(app.fileReader)
                release(app.fileReader);
                release(app.deviceWriter);
            end
            delete(app);
            
        end

        % Button pushed function: ZeroallButton
        function ZeroallButtonPushed(app, event)
            app.eqGains = zeros(1, 10); % set to zero all graphical equalizer values
            app.equalizer.Gains = app.eqGains;
            for i = 1:10
                app.sliders(i).Value = 0;
            end
        end

        % Value changed function: EqualizerSwitch
        function EqualizerSwitchValueChanged(app, event)
            value = app.EqualizerSwitch.Value;
            if value == "On"
                app.eqEnable = true;
            else
                app.eqEnable = false;
            end
        end

        % Value changing function: eqSlider1, eqSlider10, 
        % eqSlider2, eqSlider3, eqSlider4, eqSlider5, eqSlider6, 
        % eqSlider7, eqSlider8, eqSlider9
        function eqSliderValueChanging(app, event)
%             changingValue = event.Value;
            for i = 1:10
                app.eqGains(i) = app.sliders(i).Value;
            end
            app.equalizer.Gains = app.eqGains;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioplayerUIFigure = uifigure('Visible', 'off');
            app.AudioplayerUIFigure.Name = 'Audio player';
            app.AudioplayerUIFigure.CloseRequestFcn = createCallbackFcn(app, @AudioplayerUIFigureCloseRequest, true);
            app.OpenButton = uibutton(app.AudioplayerUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = 'open.png';
            app.OpenButton.Text = 'Open';
            app.Button = uibutton(app.AudioplayerUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'play.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.AudioplayerUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'pause.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.AudioplayerUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'stop.png';
            app.Button_3.Text = '';
            app.VolumeSliderLabel = uilabel(app.AudioplayerUIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.AudioplayerUIFigure);
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.Tooltip = {''};
            app.VolumeSlider.Value = 100;
            app.NowplayingEditFieldLabel = uilabel(app.AudioplayerUIFigure);
            app.NowplayingEditFieldLabel.Text = 'Now playing:';
            app.NowplayingEditField = uieditfield(app.AudioplayerUIFigure, 'text');
            app.NowplayingEditField.Editable = 'off';
            app.HzSliderLabel = uilabel(app.AudioplayerUIFigure);
            app.HzSliderLabel.Text = '31.5 Hz';
            app.eqSlider1 = uislider(app.AudioplayerUIFigure);
            app.eqSlider1.Limits = [-20 20];
            app.eqSlider1.MajorTicks = [];
            app.eqSlider1.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider1.MinorTicks = [];
            app.EqualizerSwitchLabel = uilabel(app.AudioplayerUIFigure);
            app.EqualizerSwitchLabel.Text = 'Equalizer';
            app.EqualizerSwitch = uiswitch(app.AudioplayerUIFigure, 'slider');
            app.EqualizerSwitch.ValueChangedFcn = createCallbackFcn(app, @EqualizerSwitchValueChanged, true);
            app.ZeroallButton = uibutton(app.AudioplayerUIFigure, 'push');
            app.ZeroallButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroallButtonPushed, true);
            app.ZeroallButton.Text = 'Zero all';
            app.HzLabel = uilabel(app.AudioplayerUIFigure);
            app.HzLabel.Text = '63 Hz';
            app.eqSlider2 = uislider(app.AudioplayerUIFigure);
            app.eqSlider2.Limits = [-20 20];
            app.eqSlider2.MajorTicks = [];
            app.eqSlider2.MajorTickLabels = {''};
            app.eqSlider2.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider2.MinorTicks = [];
            app.Label = uilabel(app.AudioplayerUIFigure);
            app.Label.Text = '+20';
            app.Label_2 = uilabel(app.AudioplayerUIFigure);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.AudioplayerUIFigure);
            app.Label_3.Text = '-20';
            app.HzLabel_2 = uilabel(app.AudioplayerUIFigure);
            app.HzLabel_2.Text = '125 Hz';
            app.eqSlider3 = uislider(app.AudioplayerUIFigure);
            app.eqSlider3.Limits = [-20 20];
            app.eqSlider3.MajorTicks = [];
            app.eqSlider3.MajorTickLabels = {''};
            app.eqSlider3.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider3.MinorTicks = [];
            app.HzLabel_3 = uilabel(app.AudioplayerUIFigure);
            app.HzLabel_3.Text = '250 Hz';
            app.eqSlider4 = uislider(app.AudioplayerUIFigure);
            app.eqSlider4.Limits = [-20 20];
            app.eqSlider4.MajorTicks = [];
            app.eqSlider4.MajorTickLabels = {''};
            app.eqSlider4.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider4.MinorTicks = [];
            app.HzLabel_4 = uilabel(app.AudioplayerUIFigure);
            app.HzLabel_4.Text = '500 Hz';
            app.eqSlider5 = uislider(app.AudioplayerUIFigure);
            app.eqSlider5.Limits = [-20 20];
            app.eqSlider5.MajorTicks = [];
            app.eqSlider5.MajorTickLabels = {''};
            app.eqSlider5.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider5.MinorTicks = [];
            app.kHzSliderLabel = uilabel(app.AudioplayerUIFigure);
            app.kHzSliderLabel.Text = '1 kHz';
            app.eqSlider6 = uislider(app.AudioplayerUIFigure);
            app.eqSlider6.Limits = [-20 20];
            app.eqSlider6.MajorTicks = [];
            app.eqSlider6.MajorTickLabels = {''};
            app.eqSlider6.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider6.MinorTicks = [];
            app.kHzSlider_2Label = uilabel(app.AudioplayerUIFigure);
            app.kHzSlider_2Label.Text = '2 kHz';
            app.eqSlider7 = uislider(app.AudioplayerUIFigure);
            app.eqSlider7.Limits = [-20 20];
            app.eqSlider7.MajorTicks = [];
            app.eqSlider7.MajorTickLabels = {''};
            app.eqSlider7.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider7.MinorTicks = [];
            app.kHzSlider_3Label = uilabel(app.AudioplayerUIFigure);
            app.kHzSlider_3Label.Text = '4 kHz';
            app.eqSlider8 = uislider(app.AudioplayerUIFigure);
            app.eqSlider8.Limits = [-20 20];
            app.eqSlider8.MajorTicks = [];
            app.eqSlider8.MajorTickLabels = {''};
            app.eqSlider8.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider8.MinorTicks = [];
            app.kHzSlider_4Label = uilabel(app.AudioplayerUIFigure);
            app.kHzSlider_4Label.Text = '8 kHz';
            app.eqSlider9 = uislider(app.AudioplayerUIFigure);
            app.eqSlider9.Limits = [-20 20];
            app.eqSlider9.MajorTicks = [];
            app.eqSlider9.MajorTickLabels = {''};
            app.eqSlider9.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider9.MinorTicks = [];
            app.kHzSlider_5Label = uilabel(app.AudioplayerUIFigure);
            app.kHzSlider_5Label.Text = '16 kHz';
            app.eqSlider10 = uislider(app.AudioplayerUIFigure);
            app.eqSlider10.Limits = [-20 20];
            app.eqSlider10.MajorTicks = [];
            app.eqSlider10.MajorTickLabels = {''};
            app.eqSlider10.ValueChangingFcn = createCallbackFcn(app, @eqSliderValueChanging, true);
            app.eqSlider10.MinorTicks = [];
            app.TimeLabel = uilabel(app.AudioplayerUIFigure);
            app.TimeLabel.Text = 'Time:';
            app.CurrentTimeLabel = uilabel(app.AudioplayerUIFigure);
            app.CurrentTimeLabel.Text = '00:00:00';
            app.TotalTimeLabel = uilabel(app.AudioplayerUIFigure);
            app.TotalTimeLabel.Text = '';
            app.Label_4 = uilabel(app.AudioplayerUIFigure);
            app.Label_4.Text = '/';
            % Show the figure after all components are created
            app.AudioplayerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audioplayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioplayerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioplayerUIFigure)
        end
    end
end